classdef guidedmissile < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        AnimateCheckBox            matlab.ui.control.CheckBox
        BlastRadiusEditField       matlab.ui.control.NumericEditField
        BlastRadiusEditFieldLabel  matlab.ui.control.Label
        EditField                  matlab.ui.control.NumericEditField
        TextArea                   matlab.ui.control.TextArea
        RunSimulationButton        matlab.ui.control.Button
        PositionyEditField         matlab.ui.control.NumericEditField
        PositionyLabel             matlab.ui.control.Label
        PositionxEditField         matlab.ui.control.NumericEditField
        PositionxEditFieldLabel    matlab.ui.control.Label
        VelocityyEditField         matlab.ui.control.NumericEditField
        VelocityyEditFieldLabel    matlab.ui.control.Label
        VelocityxEditField         matlab.ui.control.NumericEditField
        VelocityxEditFieldLabel    matlab.ui.control.Label
        MissileParametersLabel     matlab.ui.control.Label
        TargetPathLabel            matlab.ui.control.Label
        TimeelapsedSlider          matlab.ui.control.Slider
        TimeelapsedSliderLabel     matlab.ui.control.Label
        ytEditField                matlab.ui.control.EditField
        ytEditFieldLabel           matlab.ui.control.Label
        xtEditField                matlab.ui.control.EditField
        xtEditFieldLabel           matlab.ui.control.Label
        UIAxes                     matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        Property % Description
    end
    
    methods (Access = private)
        
        function state_dot = proportional_navigation(app, t, state, rtx, rty, N)
            % this is the funciton responsible for the actual equations
            % assign state vector
            rmx = state(1);
            rmy = state(2);
            vmx = state(3);
            vmy = state(4);

            % R and Vr computations
            R = [rmx - rtx(t); rmy - rty(t); 0];
            Vr = [vmx; vmy; 0];

            % omega
            Omega = cross(R, Vr) / dot(R, R);

            % the actual pronav fornula
            acm = -N * cross(Vr, Omega);

            % accel 
            acm = acm .* 10;
            max_accel = 10000000;  % Maximum acceleration in m/s^2
            if magnitude(acm) > max_accel
                acm = min(norm(acm), max_accel) * acm / norm(acm);
            end

            % Derivative of the state vector
            state_dot = [vmx; vmy; acm(1); acm(2)];
        end
        
       
            function idx = magtol(app,matrix1,matrix2,tolerance)
                %given two pairs of x,y values at time t, this function 
                % finds those
                % whos distance is under a given value

                % extract collumns
                m1c1 = matrix1(:,1);
                m1c2 = matrix1(:,2);
                m2c1 = matrix2(:,1);
                m2c2 = matrix2(:,2);
                % difference
                c1_diff = abs(m1c1 - m2c1);
                c2_diff = abs(m1c2 - m2c2);
                %magnitude vector
                magvect = sqrt(c1_diff.^2 + c2_diff.^2);
                idx = find(magvect <= tolerance);
                if isempty(idx) == 1
                    idx = -1; % this is for if statements in program
                end

            end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: RunSimulationButton
        function RunSimulationButtonPushed(app, event)
            app.TextArea.Value = 'Do not press any buttons until this is green.';
            app.TextArea.BackgroundColor = 'r';
            % reset axes
            cla(app.UIAxes)
            % retrieve all initial conditions
           
            % target
            rtx = str2num(app.xtEditField.Value);
            rty = str2num(app.ytEditField.Value);
            

            % really jank solution but it seems to be the only one
            rmi = [app.PositionxEditField.Value app.PositionyEditField.Value];
            vxTEMP = app.VelocityxEditField.Value;
            vyTEMP = app.VelocityyEditField.Value;
            if vxTEMP == 0
                vxTEMP = .1;
            end
            if vyTEMP == 0
                vyTEMP = .1;
            end
            vmi = [vxTEMP vyTEMP]; 
            initial_state = [rmi vmi];
            blastr = app.BlastRadiusEditField.Value + eps;
            N = 5; % navigational constant from 3-5

            tspan = [0 floor(app.TimeelapsedSlider.Value)];

            % solve ode
            [t, state] = ode45(@(t, state) proportional_navigation(app,t,state,rtx,rty,N), tspan, initial_state);            
            % t is all the times solved over, nonlinear
            % state is [posx posy velx vely]
            %have to define equation as funciton containing all kinematics
            % extra parameters passed through function
            % to have several initial conditions, delcare as vector
               % Extract states 
            mx = state(:, 1);
            my = state(:, 2);
            matrix1 = [mx my];
            matrix2 = [rtx(t) rty(t)];

            % check for when final time is 
            idx = magtol(app,matrix1,matrix2,blastr);
            if idx ~= -1
                t = t(1:idx(1));
                app.TextArea.Value = 'Intercept has been achieved.';
                app.TextArea.BackgroundColor = [0.94 0.94 0.94];
            else
                app.TextArea.Value = 'Intercept has not been achieved.';
                app.TextArea.BackgroundColor = [0.94 0.94 0.94];
            end


            % Plot the missile's trajectory
            axis(app.UIAxes,"equal")
            hold(app.UIAxes,"on");           
            if app.AnimateCheckBox.Value == 1
                steps = floor(length(t)./30); % make animation much faster hopefully
                for k = 1:steps:length(t)-1
                    plot(app.UIAxes,[mx(k) mx(k+1)], [my(k) my(k+1)],'r');
                    plot(app.UIAxes,[rtx(t(k)) rtx(t(k+1))],[rty(t(k)) rty(t(k+1))],'b');
                    pause(0.001)
                end
                app.TextArea.Value = 'Animation has ended.';
            else
                for k = 1:length(t)-1
                    plot(app.UIAxes,[mx(k) mx(k+1)], [my(k) my(k+1)],'r');
                    plot(app.UIAxes,[rtx(t(k)) rtx(t(k+1))],[rty(t(k)) rty(t(k+1))],'b');
                end
            end

            hold(app.UIAxes,"off");

        end

        % Value changed function: EditField
        function EditFieldValueChanged(app, event)
            value = app.EditField.Value;
            app.EditField.Value = floor(value);
            app.TimeelapsedSlider.Value = floor(value);
        end

        % Value changed function: TimeelapsedSlider
        function TimeelapsedSliderValueChanged(app, event)
            value = app.TimeelapsedSlider.Value;
            app.EditField.Value = floor(value);
        end

        % Value changed function: xtEditField
        function xtEditFieldValueChanged(app, event)
            value = app.xtEditField.Value;
            rtx = str2num(value);
            t = linspace(0,10,50);
             try
                rtx(t)
            catch
                app.TextArea.Value = ['x(t) MUST be a function handle. ' ...
                    'Press Run Simulation and it will crash'];
            end
       
        end

        % Value changed function: ytEditField
        function ytEditFieldValueChanged(app, event)
            value = app.ytEditField.Value;
             rtx = str2num(value);
            t = linspace(0,10,50);
             try
                rtx(t)
            catch
                app.TextArea.Value = ['y(t) MUST be a function handle. ' ...
                    'Press Run Simulation and it will crash'];
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Simulation')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.xtEditFieldLabel = uilabel(app.UIFigure);
            app.xtEditFieldLabel.Text = 'x(t)';
            app.xtEditField = uieditfield(app.UIFigure, 'text');
            app.xtEditField.ValueChangedFcn = createCallbackFcn(app, @xtEditFieldValueChanged, true);
            app.xtEditField.Tooltip = {'If this is not a properly defined function_handle, will crash.'};
            app.xtEditField.Value = '@(t) sin((t*pi)/10)*25';
            app.ytEditFieldLabel = uilabel(app.UIFigure);
            app.ytEditFieldLabel.Text = 'y(t)';
            app.ytEditField = uieditfield(app.UIFigure, 'text');
            app.ytEditField.ValueChangedFcn = createCallbackFcn(app, @ytEditFieldValueChanged, true);
            app.ytEditField.Value = '@(t) cos(t)*75';
            app.TimeelapsedSliderLabel = uilabel(app.UIFigure);
            app.TimeelapsedSliderLabel.Text = 'Time elapsed';
            app.TimeelapsedSlider = uislider(app.UIFigure);
            app.TimeelapsedSlider.Limits = [1 60];
            app.TimeelapsedSlider.MajorTicks = [10 20 30 40 50 60];
            app.TimeelapsedSlider.ValueChangedFcn = createCallbackFcn(app, @TimeelapsedSliderValueChanged, true);
            app.TimeelapsedSlider.MinorTicks = [];
            app.TimeelapsedSlider.Value = 1;
            app.TargetPathLabel = uilabel(app.UIFigure);
            app.TargetPathLabel.Text = 'Target Path';
            app.MissileParametersLabel = uilabel(app.UIFigure);
            app.MissileParametersLabel.Text = 'Missile Parameters';
            app.VelocityxEditFieldLabel = uilabel(app.UIFigure);
            app.VelocityxEditFieldLabel.Text = 'Velocity x';
            app.VelocityxEditField = uieditfield(app.UIFigure, 'numeric');
            app.VelocityxEditField.Value = 10;
            app.VelocityyEditFieldLabel = uilabel(app.UIFigure);
            app.VelocityyEditFieldLabel.Text = 'Velocity y';
            app.VelocityyEditField = uieditfield(app.UIFigure, 'numeric');
            app.VelocityyEditField.Value = 10;
            app.PositionxEditFieldLabel = uilabel(app.UIFigure);
            app.PositionxEditFieldLabel.Text = 'Position x';
            app.PositionxEditField = uieditfield(app.UIFigure, 'numeric');
            app.PositionyLabel = uilabel(app.UIFigure);
            app.PositionyLabel.Text = 'Position y';
            app.PositionyEditField = uieditfield(app.UIFigure, 'numeric');
            app.PositionyEditField.Value = -25;
            app.RunSimulationButton = uibutton(app.UIFigure, 'push');
            app.RunSimulationButton.ButtonPushedFcn = createCallbackFcn(app, @RunSimulationButtonPushed, true);
            app.RunSimulationButton.Text = 'Run Simulation';
            app.TextArea = uitextarea(app.UIFigure);
            app.EditField = uieditfield(app.UIFigure, 'numeric');
            app.EditField.Limits = [1 60];
            app.EditField.ValueChangedFcn = createCallbackFcn(app, @EditFieldValueChanged, true);
            app.EditField.Value = 20;
            app.BlastRadiusEditFieldLabel = uilabel(app.UIFigure);
            app.BlastRadiusEditFieldLabel.Text = 'Blast Radius';
            app.BlastRadiusEditField = uieditfield(app.UIFigure, 'numeric');
            app.BlastRadiusEditField.Limits = [0 Inf];
            app.BlastRadiusEditField.Value = 2;
            app.AnimateCheckBox = uicheckbox(app.UIFigure);
            app.AnimateCheckBox.Text = 'Animate?';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = guidedmissile

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end